classdef axicon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AxiconUIFigure                 matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        SizeEditFieldLabel             matlab.ui.control.Label
        SizeEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        ProfileDropDownLabel           matlab.ui.control.Label
        ProfileDropDown                matlab.ui.control.DropDown
        GradientSpinnerLabel           matlab.ui.control.Label
        GradientSpinner                matlab.ui.control.Spinner
        AngledegSpinnerLabel           matlab.ui.control.Label
        AngledegSpinner                matlab.ui.control.Spinner
        CentreEditFieldLabel           matlab.ui.control.Label
        CentreEditField                matlab.ui.control.EditField
        AspectSpinnerLabel             matlab.ui.control.Label
        AspectSpinner                  matlab.ui.control.Spinner
        UIAxes                         matlab.ui.control.UIAxes
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end


    methods (Access = public)
    
        function updateView(app)
            % Redraw the main display
            app.patternValueChanged([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Populate the display device field
            otslm.ui.support.populateDeviceList(app.DeviceDropDown);
            
            % Generate the initial pattern
            app.patternValueChanged([]);
        end

        % Value changed function: AngledegSpinner, AspectSpinner, 
        % CentreEditField, DeviceDropDown, DisplayDropDown, 
        % DisplayVariableEditField, EnableDisplayCheckBox, 
        % GradientSpinner, NameEditField, ProfileDropDown, 
        % SizeEditField, UpdateDeviceCheckBox
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            sz = evalin('base', ['[', app.SizeEditField.Value, ']']);
            gradient = app.GradientSpinner.Value;
            angle_deg = app.AngledegSpinner.Value;
            aspect = app.AspectSpinner.Value;
            type = app.ProfileDropDown.Value;
            centre = evalin('base', ['[', app.CentreEditField.Value, ']']);
            
            % Generate the pattern
            pattern = otslm.simple.axicon(sz, gradient, ...
                'centre', centre, 'angle_deg', angle_deg, ...
                'aspect', aspect, 'type', type);
            
            % Offload to the base class (sort of...)
            otslm.ui.support.simplePatternValueChanged(name, pattern, ...
                app.DeviceDropDown.Value, app.UpdateDeviceCheckBox.Value, ...
                app.EnableDisplayCheckBox.Value, app.UIAxes, ...
                app.DisplayDropDown.Value, app.DisplayVariableEditField.Value);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AxiconUIFigure = uifigure;
            app.AxiconUIFigure.Name = 'Axicon';
            app.Panel = uipanel(app.AxiconUIFigure);
            app.SizeEditFieldLabel = uilabel(app.Panel);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.Panel, 'text');
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.SizeEditField.Value = '256,256';
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.ProfileDropDownLabel = uilabel(app.Panel);
            app.ProfileDropDownLabel.Text = 'Profile';
            app.ProfileDropDown = uidropdown(app.Panel);
            app.ProfileDropDown.Items = {'2-Dimensional', '1-Dimensional'};
            app.ProfileDropDown.ItemsData = {'2d', '1d'};
            app.ProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.ProfileDropDown.Value = '2d';
            app.GradientSpinnerLabel = uilabel(app.Panel);
            app.GradientSpinnerLabel.Text = 'Gradient';
            app.GradientSpinner = uispinner(app.Panel);
            app.GradientSpinner.Step = 0.01;
            app.GradientSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.GradientSpinner.Value = 0.1;
            app.AngledegSpinnerLabel = uilabel(app.Panel);
            app.AngledegSpinnerLabel.Text = 'Angle (deg)';
            app.AngledegSpinner = uispinner(app.Panel);
            app.AngledegSpinner.Step = 5;
            app.AngledegSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.CentreEditFieldLabel = uilabel(app.Panel);
            app.CentreEditFieldLabel.Text = 'Centre';
            app.CentreEditField = uieditfield(app.Panel, 'text');
            app.CentreEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.CentreEditField.Value = '128, 128';
            app.AspectSpinnerLabel = uilabel(app.Panel);
            app.AspectSpinnerLabel.Text = 'Aspect';
            app.AspectSpinner = uispinner(app.Panel);
            app.AspectSpinner.Step = 0.1;
            app.AspectSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.AspectSpinner.Value = 1;
            app.UIAxes = uiaxes(app.AxiconUIFigure);
            app.UpdateDeviceCheckBox = uicheckbox(app.AxiconUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.AxiconUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.AxiconUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.AxiconUIFigure);
            app.DisplayDropDown.Items = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Phase mask';
            app.DisplayVariableEditFieldLabel = uilabel(app.AxiconUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.AxiconUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDownLabel = uilabel(app.AxiconUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.AxiconUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = axicon

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AxiconUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AxiconUIFigure)
        end
    end
end