classdef zernike < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZernikePolynomialUIFigure      matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        SizeEditFieldLabel             matlab.ui.control.Label
        SizeEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        IndexmSpinnerLabel             matlab.ui.control.Label
        IndexmSpinner                  matlab.ui.control.Spinner
        IndexnSpinnerLabel             matlab.ui.control.Label
        IndexnSpinner                  matlab.ui.control.Spinner
        AngledegSpinnerLabel           matlab.ui.control.Label
        AngledegSpinner                matlab.ui.control.Spinner
        RadiusSpinnerLabel             matlab.ui.control.Label
        RadiusSpinner                  matlab.ui.control.Spinner
        CentreEditFieldLabel           matlab.ui.control.Label
        CentreEditField                matlab.ui.control.EditField
        ScaleSpinnerLabel              matlab.ui.control.Label
        ScaleSpinner                   matlab.ui.control.Spinner
        AspectSpinnerLabel             matlab.ui.control.Label
        AspectSpinner                  matlab.ui.control.Spinner
        OutsideEditFieldLabel          matlab.ui.control.Label
        OutsideEditField               matlab.ui.control.EditField
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end


    methods (Access = public)
    
        function updateView(app)
            % Redraw the main display
            app.patternValueChanged([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Populate the display device field
            otslm.ui.support.populateDeviceList(app.DeviceDropDown);
            
            % Generate the initial pattern
            app.patternValueChanged([]);
        end

        % Value changed function: AngledegSpinner, AspectSpinner, 
        % CentreEditField, DeviceDropDown, DisplayDropDown, 
        % DisplayVariableEditField, EnableDisplayCheckBox, 
        % IndexmSpinner, NameEditField, OutsideEditField, RadiusSpinner, 
        % ScaleSpinner, SizeEditField, UpdateDeviceCheckBox
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            sz = evalin('base', ['[', app.SizeEditField.Value, ']']);
            indexm = app.IndexmSpinner.Value;
            indexn = app.IndexnSpinner.Value;
            angle_deg = app.AngledegSpinner.Value;
            radius = app.RadiusSpinner.Value;
            scale = app.ScaleSpinner.Value;
            aspect = app.AspectSpinner.Value;
            centre = evalin('base', ['[', app.CentreEditField.Value, ']']);
            outside = evalin('base', ['[', app.OutsideEditField.Value, ']']);
            
            % Generate the pattern
            pattern = otslm.simple.zernike(sz, indexm, indexn, ...
                'centre', centre, 'angle_deg', angle_deg, 'scale', scale, ...
                'rscale', radius, 'aspect', aspect, 'outside', outside);
            
            % Offload to the base class (sort of...)
            otslm.ui.support.simplePatternValueChanged(name, pattern, ...
                app.DeviceDropDown.Value, app.UpdateDeviceCheckBox.Value, ...
                app.EnableDisplayCheckBox.Value, app.UIAxes, ...
                app.DisplayDropDown.Value, app.DisplayVariableEditField.Value);
            
        end

        % Value changed function: IndexnSpinner
        function IndexnSpinnerValueChanged(app, event)
            if app.IndexnSpinner.Value == 0
                app.IndexmSpinner.Value = 0;
                app.IndexmSpinner.Enable = 'off';
            else
                app.IndexmSpinner.Limits = app.IndexnSpinner.Value.*[-1, 1];
                app.IndexmSpinner.Enable = 'on';
            end
            app.patternValueChanged(event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZernikePolynomialUIFigure = uifigure;
            app.ZernikePolynomialUIFigure.Name = 'Zernike Polynomial';
            app.UIAxes = uiaxes(app.ZernikePolynomialUIFigure);
            app.Panel = uipanel(app.ZernikePolynomialUIFigure);
            app.SizeEditFieldLabel = uilabel(app.Panel);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.Panel, 'text');
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.SizeEditField.Value = '256,256';
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.IndexmSpinnerLabel = uilabel(app.Panel);
            app.IndexmSpinnerLabel.Text = 'Index m';
            app.IndexmSpinner = uispinner(app.Panel);
            app.IndexmSpinner.Limits = [-2 2];
            app.IndexmSpinner.RoundFractionalValues = 'on';
            app.IndexmSpinner.ValueDisplayFormat = '%.0f';
            app.IndexmSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.IndexnSpinnerLabel = uilabel(app.Panel);
            app.IndexnSpinnerLabel.Text = 'Index n';
            app.IndexnSpinner = uispinner(app.Panel);
            app.IndexnSpinner.Limits = [0 Inf];
            app.IndexnSpinner.RoundFractionalValues = 'on';
            app.IndexnSpinner.ValueDisplayFormat = '%.0f';
            app.IndexnSpinner.ValueChangedFcn = createCallbackFcn(app, @IndexnSpinnerValueChanged, true);
            app.IndexnSpinner.Value = 2;
            app.AngledegSpinnerLabel = uilabel(app.Panel);
            app.AngledegSpinnerLabel.Text = 'Angle (deg)';
            app.AngledegSpinner = uispinner(app.Panel);
            app.AngledegSpinner.Step = 5;
            app.AngledegSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.RadiusSpinnerLabel = uilabel(app.Panel);
            app.RadiusSpinnerLabel.Text = 'Radius';
            app.RadiusSpinner = uispinner(app.Panel);
            app.RadiusSpinner.LowerLimitInclusive = 'off';
            app.RadiusSpinner.Limits = [0 Inf];
            app.RadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.RadiusSpinner.Value = 128;
            app.CentreEditFieldLabel = uilabel(app.Panel);
            app.CentreEditFieldLabel.Text = 'Centre';
            app.CentreEditField = uieditfield(app.Panel, 'text');
            app.CentreEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.CentreEditField.Value = '128, 128';
            app.ScaleSpinnerLabel = uilabel(app.Panel);
            app.ScaleSpinnerLabel.Text = 'Scale';
            app.ScaleSpinner = uispinner(app.Panel);
            app.ScaleSpinner.Step = 0.1;
            app.ScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.ScaleSpinner.Value = 1;
            app.AspectSpinnerLabel = uilabel(app.Panel);
            app.AspectSpinnerLabel.Text = 'Aspect';
            app.AspectSpinner = uispinner(app.Panel);
            app.AspectSpinner.Step = 0.1;
            app.AspectSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.AspectSpinner.Value = 1;
            app.OutsideEditFieldLabel = uilabel(app.Panel);
            app.OutsideEditFieldLabel.Text = 'Outside';
            app.OutsideEditField = uieditfield(app.Panel, 'text');
            app.OutsideEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.OutsideEditField.Value = '0.0';
            app.UpdateDeviceCheckBox = uicheckbox(app.ZernikePolynomialUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.ZernikePolynomialUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.ZernikePolynomialUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.ZernikePolynomialUIFigure);
            app.DisplayDropDown.Items = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Phase mask';
            app.DisplayVariableEditFieldLabel = uilabel(app.ZernikePolynomialUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.ZernikePolynomialUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDownLabel = uilabel(app.ZernikePolynomialUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.ZernikePolynomialUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = zernike

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZernikePolynomialUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZernikePolynomialUIFigure)
        end
    end
end