classdef Dither < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DitherUIFigure                 matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        MethodDropDownLabel            matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        LevelSpinnerLabel              matlab.ui.control.Label
        LevelSpinner                   matlab.ui.control.Spinner
        InputDropDownLabel             matlab.ui.control.Label
        InputDropDown                  matlab.ui.control.DropDown
        ValuesEditFieldLabel           matlab.ui.control.Label
        ValuesEditField                matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end


    properties (Access = private)
        oldImages;      % Old images from last watch
        watchTimer;     % Timer to watch if input images change
    end

    methods (Access = private)
    
        function checkImagesChanged(app, timer, event)
            
            % Get latest images
            newImages = otslm.ui.support.getImageOrNone(app.InputDropDown.Value, true);
            
            % Check for changes
            changed = otslm.ui.support.checkImagesChanged({newImages}, {app.oldImages});
            
            % If we changed, update the display
            if changed
                app.patternValueChanged([]);
            end
        end
        
    end
    
    methods (Access = public)
        
        function updateView(app)
            % Redraw the main display
            app.patternValueChanged([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            varnames = evalin('base', 'who');
            
            % Populate input array with variables
            app.InputDropDown.Items = varnames;
            app.InputDropDown.Value = '';
            
            % Populate the display device field
            otslm.ui.support.populateDeviceList(app.DeviceDropDown);
            
            % Generate the initial pattern
            app.patternValueChanged([]);
            
            % Setup a timer to watch the images
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.checkImagesChanged;
            start(app.watchTimer);
        end

        % Value changed function: DeviceDropDown, DisplayDropDown, 
        % DisplayVariableEditField, EnableDisplayCheckBox, 
        % InputDropDown, LevelSpinner, MethodDropDown, NameEditField, 
        % UpdateDeviceCheckBox
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            inputname = app.InputDropDown.Value;
            method = app.MethodDropDown.Value;
            level = app.LevelSpinner.Value;
            values = evalin('base', ['[', app.ValuesEditField.Value, ']']);
            
            % Get the input image
            app.oldImages = otslm.ui.support.getImageOrNone(inputname);
            
            % Generate the pattern
            pattern = otslm.tools.dither(app.oldImages, level, ...
                'method', method, 'value', values);
            
            % Offload to the base class (sort of...)
            otslm.ui.support.simplePatternValueChanged(name, pattern, ...
                app.DeviceDropDown.Value, app.UpdateDeviceCheckBox.Value, ...
                app.EnableDisplayCheckBox.Value, app.UIAxes, ...
                app.DisplayDropDown.Value, app.DisplayVariableEditField.Value);
            
        end

        % Close request function: DitherUIFigure
        function DitherUIFigureCloseRequest(app, event)
            otslm.ui.support.cleanTimer(app.watchTimer);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DitherUIFigure = uifigure;
            app.DitherUIFigure.Name = 'Dither';
            app.DitherUIFigure.CloseRequestFcn = createCallbackFcn(app, @DitherUIFigureCloseRequest, true);
            app.Panel = uipanel(app.DitherUIFigure);
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.MethodDropDownLabel = uilabel(app.Panel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Panel);
            app.MethodDropDown.Items = {'Matlab Dither', 'Floyd', 'Random', 'Threshold'};
            app.MethodDropDown.ItemsData = {'mdither', 'floyd', 'random', 'threshold'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.MethodDropDown.Value = 'floyd';
            app.LevelSpinnerLabel = uilabel(app.Panel);
            app.LevelSpinnerLabel.Text = 'Level';
            app.LevelSpinner = uispinner(app.Panel);
            app.LevelSpinner.Step = 0.1;
            app.LevelSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.LevelSpinner.Value = 0.5;
            app.InputDropDownLabel = uilabel(app.Panel);
            app.InputDropDownLabel.Text = 'Input';
            app.InputDropDown = uidropdown(app.Panel);
            app.InputDropDown.Items = {};
            app.InputDropDown.Editable = 'on';
            app.InputDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.InputDropDown.Value = {};
            app.ValuesEditFieldLabel = uilabel(app.Panel);
            app.ValuesEditFieldLabel.Text = 'Values';
            app.ValuesEditField = uieditfield(app.Panel, 'text');
            app.ValuesEditField.Value = '0, 0.5';
            app.UIAxes = uiaxes(app.DitherUIFigure);
            app.UpdateDeviceCheckBox = uicheckbox(app.DitherUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.DitherUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.DitherUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.DitherUIFigure);
            app.DisplayDropDown.Items = {'Device image', 'Simulated farfield', 'Raw pattern'};
            app.DisplayDropDown.ItemsData = {'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Raw phase mask';
            app.DisplayVariableEditFieldLabel = uilabel(app.DitherUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.DitherUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDownLabel = uilabel(app.DitherUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.DitherUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Dither

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DitherUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DitherUIFigure)
        end
    end
end