classdef Encode1d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Encode1DUIFigure               matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        InputDropDownLabel             matlab.ui.control.Label
        InputDropDown                  matlab.ui.control.DropDown
        ScaleSpinnerLabel              matlab.ui.control.Label
        ScaleSpinner                   matlab.ui.control.Spinner
        AngledegSpinnerLabel           matlab.ui.control.Label
        AngledegSpinner                matlab.ui.control.Spinner
        UIAxes                         matlab.ui.control.UIAxes
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        oldImage;       % Old images from last watch
        watchTimer;     % Timer to watch if input images change
    end

    methods (Access = private)
        
        function checkImagesChanged(app, timer, event)
            
            % Get latest images
            newImage = otslm.ui.support.getImageOrNone(app.InputDropDown.Value, true);
            
            % Check for changes
            changed = otslm.ui.support.checkImagesChanged({newImage}, {app.oldImage});
            
            % If we changed, update the display
            if changed
                app.patternValueChanged([]);
            end
        end
        
    end
    
    methods (Access = public)
        
        function updateView(app)
            % Redraw the main display
            app.calculateData([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            varnames = evalin('base', 'who');
            
            % Populate input array with variables
            app.InputDropDown.Items = varnames;
            app.InputDropDown.Value = '';
            
            % Populate the display device field
            otslm.ui.support.populateDeviceList(app.DeviceDropDown);
            
            % Generate the initial pattern
            app.patternValueChanged([]);
            
            % Setup a timer to watch the images
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.checkImagesChanged;
            start(app.watchTimer);
        end

        % Value changed function: AngledegSpinner, DeviceDropDown, 
        % DisplayDropDown, DisplayVariableEditField, 
        % EnableDisplayCheckBox, InputDropDown, NameEditField, 
        % ScaleSpinner, UpdateDeviceCheckBox
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            inputname = app.InputDropDown.Value;
            angle_deg = app.AngledegSpinner.Value;
            scale = app.ScaleSpinner.Value;
            
            % Get the input image
            app.oldImage = otslm.ui.support.getImageOrNone(inputname);
            
            % Generate the pattern
            pattern = otslm.tools.encode1d(app.oldImage, ...
                'scale', scale, 'angle_deg', angle_deg);
            
            % Offload to the base class (sort of...)
            otslm.ui.support.simplePatternValueChanged(name, pattern, ...
                app.DeviceDropDown.Value, app.UpdateDeviceCheckBox.Value, ...
                app.EnableDisplayCheckBox.Value, app.UIAxes, ...
                app.DisplayDropDown.Value, app.DisplayVariableEditField.Value);
            
        end

        % Close request function: Encode1DUIFigure
        function Encode1DUIFigureCloseRequest(app, event)
            otslm.ui.support.cleanTimer(app.watchTimer);
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Encode1DUIFigure = uifigure;
            app.Encode1DUIFigure.Name = 'Encode 1D';
            app.Encode1DUIFigure.CloseRequestFcn = createCallbackFcn(app, @Encode1DUIFigureCloseRequest, true);
            app.Panel = uipanel(app.Encode1DUIFigure);
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.InputDropDownLabel = uilabel(app.Panel);
            app.InputDropDownLabel.Text = 'Input';
            app.InputDropDown = uidropdown(app.Panel);
            app.InputDropDown.Items = {};
            app.InputDropDown.Editable = 'on';
            app.InputDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.InputDropDown.Value = {};
            app.ScaleSpinnerLabel = uilabel(app.Panel);
            app.ScaleSpinnerLabel.Text = 'Scale';
            app.ScaleSpinner = uispinner(app.Panel);
            app.ScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.ScaleSpinner.Value = 1;
            app.AngledegSpinnerLabel = uilabel(app.Panel);
            app.AngledegSpinnerLabel.Text = 'Angle (deg)';
            app.AngledegSpinner = uispinner(app.Panel);
            app.AngledegSpinner.Step = 5;
            app.AngledegSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UIAxes = uiaxes(app.Encode1DUIFigure);
            app.UpdateDeviceCheckBox = uicheckbox(app.Encode1DUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.Encode1DUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.Encode1DUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.Encode1DUIFigure);
            app.DisplayDropDown.Items = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Phase mask';
            app.DisplayVariableEditFieldLabel = uilabel(app.Encode1DUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.Encode1DUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDownLabel = uilabel(app.Encode1DUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.Encode1DUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Encode1d

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Encode1DUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Encode1DUIFigure)
        end
    end
end